classdef DecorrApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        ResetAppMenu                  matlab.ui.container.Menu
        ImportImagingSettingsMenu     matlab.ui.container.Menu
        ImportControlSettingsMenu     matlab.ui.container.Menu
        ExportImagingSettingsMenu     matlab.ui.container.Menu
        ExportControlSettingsMenu     matlab.ui.container.Menu
        TestSerialConnectionMenu      matlab.ui.container.Menu
        ControlMenu                   matlab.ui.container.Menu
        ResetControlParametersMenu    matlab.ui.container.Menu
        Menu                          matlab.ui.container.Menu
        ReviewMenu                    matlab.ui.container.Menu
        TabGroup                      matlab.ui.container.TabGroup
        SetupTab                      matlab.ui.container.Tab
        SerialConnectionsPanel        matlab.ui.container.Panel
        RFControllerLabel             matlab.ui.control.Label
        RFControllerDropDown          matlab.ui.control.DropDown
        RFOutputDropDownLabel         matlab.ui.control.Label
        RFOutputDropDown              matlab.ui.control.DropDown
        VerifyButton                  matlab.ui.control.Button
        VerifyButton_2                matlab.ui.control.Button
        EnableSwitchLabel             matlab.ui.control.Label
        EnableSwitch                  matlab.ui.control.Switch
        EnableSwitch_2Label           matlab.ui.control.Label
        EnableSwitch_2                matlab.ui.control.Switch
        GeneralControlsPanel          matlab.ui.container.Panel
        ModeButtonGroup               matlab.ui.container.ButtonGroup
        ControlModeButton             matlab.ui.control.RadioButton
        PrerecordedDataModeButton     matlab.ui.control.RadioButton
        ImagingModeButton             matlab.ui.control.RadioButton
        azel_init_axes                matlab.ui.control.UIAxes
        ImagingSettingsPanel          matlab.ui.container.Panel
        AzimuthAngleEditFieldLabel    matlab.ui.control.Label
        AzimuthAngleEditField         matlab.ui.control.NumericEditField
        ElevationAngleEditFieldLabel  matlab.ui.control.Label
        ElevationAngleEditField       matlab.ui.control.NumericEditField
        FrameRateEditFieldLabel       matlab.ui.control.Label
        FrameRateEditField            matlab.ui.control.NumericEditField
        CartScalingFactorEditFieldLabel  matlab.ui.control.Label
        CartScalingFactorEditField    matlab.ui.control.NumericEditField
        TargetDirectoryPanel          matlab.ui.container.Panel
        SetDirectoryButton            matlab.ui.control.Button
        DirectoryLabel                matlab.ui.control.Label
        ROISettingsPanel              matlab.ui.container.Panel
        ROIModeDropDownLabel          matlab.ui.control.Label
        ROIModeDropDown               matlab.ui.control.DropDown
        ROICenterLabel                matlab.ui.control.Label
        OuterRadiiLabel               matlab.ui.control.Label
        InnerRadiiLabel               matlab.ui.control.Label
        x0EditFieldLabel              matlab.ui.control.Label
        x0EditField                   matlab.ui.control.NumericEditField
        y0EditFieldLabel              matlab.ui.control.Label
        y0EditField                   matlab.ui.control.NumericEditField
        z0EditFieldLabel              matlab.ui.control.Label
        z0EditField                   matlab.ui.control.NumericEditField
        Rx_outEditFieldLabel          matlab.ui.control.Label
        Rx_outEditField               matlab.ui.control.NumericEditField
        Ry_outEditFieldLabel          matlab.ui.control.Label
        Ry_outEditField               matlab.ui.control.NumericEditField
        Rz_outEditFieldLabel          matlab.ui.control.Label
        Rz_outEditField               matlab.ui.control.NumericEditField
        Rx_inEditFieldLabel           matlab.ui.control.Label
        Rx_inEditField                matlab.ui.control.NumericEditField
        Ry_inEditFieldLabel           matlab.ui.control.Label
        Ry_inEditField                matlab.ui.control.NumericEditField
        Rz_inEditFieldLabel           matlab.ui.control.Label
        Rz_inEditField                matlab.ui.control.NumericEditField
        AngleLabel                    matlab.ui.control.Label
        AlphaEditFieldLabel           matlab.ui.control.Label
        AlphaEditField                matlab.ui.control.NumericEditField
        BetaEditFieldLabel            matlab.ui.control.Label
        BetaEditField                 matlab.ui.control.NumericEditField
        GammaEditFieldLabel           matlab.ui.control.Label
        GammaEditField                matlab.ui.control.NumericEditField
        RangeSliderLabel              matlab.ui.control.Label
        RangeSlider                   matlab.ui.control.Slider
        rael_init_axes                matlab.ui.control.UIAxes
        AzimuthSliderLabel            matlab.ui.control.Label
        AzimuthSlider                 matlab.ui.control.Slider
        raaz_init_axes                matlab.ui.control.UIAxes
        ElevationSliderLabel          matlab.ui.control.Label
        ElevationSlider               matlab.ui.control.Slider
        RequestButton                 matlab.ui.control.Button
        RequestInitVolumeLabel        matlab.ui.control.Label
        EtcPanel                      matlab.ui.container.Panel
        UpdateAllButton               matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        ExportSettingsButton          matlab.ui.control.Button
        ImportSettingsButton          matlab.ui.control.Button
        DynamicRangeSliderLabel       matlab.ui.control.Label
        DynamicRangeSlider            matlab.ui.control.Slider
        surf_init_axes                matlab.ui.control.UIAxes
        ImagingTab                    matlab.ui.container.Tab
        Panel                         matlab.ui.container.Panel
        RecordVolumeButton            matlab.ui.control.Button
        ButtonGroup                   matlab.ui.container.ButtonGroup
        ManualImagingMdoeButton       matlab.ui.control.RadioButton
        AutomaticImagingModeButton    matlab.ui.control.RadioButton
        ManualControlModeButton       matlab.ui.control.RadioButton
        AutomaticControlModeButton    matlab.ui.control.RadioButton
        ReadytoRecordLampLabel        matlab.ui.control.Label
        ReadytoRecordLamp             matlab.ui.control.Lamp
        DecorrThreshEditFieldLabel    matlab.ui.control.Label
        DecorrThreshEditField         matlab.ui.control.NumericEditField
        sigmaEditFieldLabel           matlab.ui.control.Label
        sigmaEditField                matlab.ui.control.NumericEditField
        decorr_imaging_axes           matlab.ui.control.UIAxes
        azel_imaging_axes             matlab.ui.control.UIAxes
        rael_imaging_axes             matlab.ui.control.UIAxes
        raaz_imaging_axes             matlab.ui.control.UIAxes
        RangeSlider_2Label            matlab.ui.control.Label
        RangeSlider_imaging           matlab.ui.control.Slider
        AzimuthSlider_2Label          matlab.ui.control.Label
        AzimuthSlider_imaging         matlab.ui.control.Slider
        ElevationSlider_2Label        matlab.ui.control.Label
        ElevationSlider_imaging       matlab.ui.control.Slider
        DynamicRangeSlider_2Label     matlab.ui.control.Label
        DynamicRangeSlider_imaging    matlab.ui.control.Slider
        ReviewTab                     matlab.ui.container.Tab
        UIAxes_5                      matlab.ui.control.UIAxes
        UIAxes_6                      matlab.ui.control.UIAxes
        UIAxes_7                      matlab.ui.control.UIAxes
        UIAxes_8                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        experiment; % Main experiment class
        isoDisplay;
        ROISettingsList = {'ROISettingsPanel','ROIModeDropDownLabel','ROIModeDropDown','ROICenterLabel', ...
                            'OuterRadiiLabel','InnerRadiiLabel','x0EditFieldLabel','x0EditField','y0EditFieldLabel',...
                            'y0EditField','z0EditFieldLabel','z0EditField','Rx_outEditFieldLabel','Rx_outEditField',...
                            'Ry_outEditFieldLabel','Ry_outEditField','Rz_outEditFieldLabel','Rz_outEditField',...
                            'Rx_inEditFieldLabel','Rx_inEditField','Ry_inEditFieldLabel','Ry_inEditField',...
                            'Rz_inEditFieldLabel','Rz_inEditField'};
    end
    
    methods (Access = public)
        function changeUIExperimentState(app)
            experimentState = app.experiment.machineState; 
            switch experimentState
                case 0
                    activeComponents = {};
                    inactiveComponents = {};
                case 1
                    activeComponents = {};
                    inactiveComponents = {};
                case 2
                    activeComponents = {};
                    inactiveComponents = {};
                case 3
                    activeComponents = {};
                    inactiveComponents = {};
                case 4
                    activeComponents = {};
                    inactiveComponents = {};
                case 5
                    activeComponents = {};
                    inactiveComponents = {};
                case 6
                    activeComponents = {};
                    inactiveComponents = {};
                otherwise
                    
            end
            for currUIElem = 1:numel(activeComponents)
                app.(activeComponents{currUIElem}).Enable = true;
            end
            
            for currUIElem = 1:numel(inactiveComponents)
                app.(activeComponents{currUIElem}).Enable = false;
            end
        end
        function updateImagingParams(app)
            % Get params from GUI
            thisphimax = deg2rad(app.ElevationAngleEditField.Value/2);
            thisphimin = -thisphimax;
            thisthetamax = deg2rad(app.AzimuthAngleEditField.Value/2);
            thisthetamin = -thisphimax;
            thiscartScalingFactor = app.CartScalingFactorEditField.Value; 
            thisinterFrameTime = app.FrameRateEditField.Value; 
            % Init in the main experiment object
            app.experiment.setImagingParams(thisthetamin,thisthetamax,thisphimin,thisphimax,thiscartScalingFactor,thisinterFrameTime)
        end
        function updateROIParams(app)
            x0 = app.x0EditField.Value;
            y0 = app.y0EditField.Value; 
            z0 = app.z0EditField.Value; 
            Rx_in = app.Rx_inEditField.Value;
            Ry_in = app.Ry_inEditField.Value;
            Rz_in = app.Rz_inEditField.Value;
            Rx_out = app.Rx_outEditField.Value;
            Ry_out = app.Ry_outEditField.Value;
            Rz_out = app.Rz_outEditField.Value;
            alphaAng = app.AlphaEditField.Value; 
            betaAng = app.BetaEditField.Value; 
            gammaAng = app.GammaEditField.Value; 
            ROIMode = app.ROIModeDropDown.Value;
            app.experiment.setROIParams(x0,y0,z0,Rx_out,Ry_out,Rz_out,Rx_in,Ry_in,Rz_in,alphaAng,gammaAng,betaAng);
            updateInitAxes(app);
            testROI = app.experiment.ROIMap; 
            cla(app.surf_init_axes);
                app.isoDisplay = isosurface(testROI); 
                hold(app.surf_init_axes,'on')
            patch(app.surf_init_axes ,app.isoDisplay,'FaceColor','red');
            [xSize,ySize,zSize,~] = size(app.experiment.initDataSet.rawData_cart);
            axis(app.surf_init_axes, [0 xSize 0 ySize 0 zSize])
            [x_pz, y_pz] = meshgrid(0:1:xSize,0:1:ySize);
            z_pz = (zSize-floor(app.RangeSlider.Value))*ones(size(x_pz)); %-zMid;
            [x_py, z_py] = meshgrid(0:1:xSize,0:1:zSize);
            y_py = (ySize-floor(app.AzimuthSlider.Value))*ones(size(x_py)); %-yMid;
            [y_px, z_px] = meshgrid(0:1:ySize,0:1:zSize);
            x_px = (xSize-floor(app.ElevationSlider.Value))*ones(size(y_px)); %-xMid;
            pz_surf = surf(app.surf_init_axes,y_pz,z_pz,x_pz);
            px_surf = surf(app.surf_init_axes,y_py,z_py,x_py);
            py_surf = surf(app.surf_init_axes,y_px,z_px,x_px);
            hold(app.surf_init_axes,'off')
        end
        
        function initInitAxes(app)
            [xMax,yMax,zMax,frames] = size(app.experiment.initDataSet.rawData_cart);
            app.AzimuthSlider.Limits = [1, yMax];
            app.ElevationSlider.Limits = [1,zMax];
            app.RangeSlider.Limits = [1,xMax];
        end
        function initImagingAxes(app)
            [xMax,yMax,zMax,~] = size(app.experiment.initDataSet.rawData_cart);
            app.AzimuthSlider_imaging.Limits = [1, yMax];
            app.ElevationSlider_imaging.Limits = [1,zMax];
            app.RangeSlider_imaging.Limits = [1,xMax];
        end
        
        function updateInitAxes(app)
            %%% vars %%%
            %% Get Slider Values
            azim = floor(app.AzimuthSlider.Value);
            elev = floor(app.ElevationSlider.Value); 
            ra = floor(app.RangeSlider.Value); 
            %% get volume for display
            displayVol = log10(abs(squeeze(app.experiment.initDataSet.rawData_cart(:,:,:,1)))); 
            [xSize,ySize,zSize] = size(displayVol);
            %% get ROI 
            testROI = app.experiment.ROIMap; 
            overLayImRaaz = edge(squeeze(testROI(elev,:,:))); %range azimuth
            overLayImAzel = edge(squeeze(testROI(:,:,ra))); % azimuth elevation
            overLayImRael = edge(squeeze(testROI(:,azim,:))); % range elevation
            outRx = app.experiment.ROIr0; % x Radii
            outRy = app.experiment.ROIr1; % y Radii
            outRz = app.experiment.ROIr1; 
            x0 = app.experiment.ROIx0; 
            y0 = app.experiment.ROIy0; 
            z0 = app.experiment.ROIz0;
            
            %%% Plots %%%
            
            %% az-el %%
            cla(app.azel_init_axes);
            hold(app.azel_init_axes,'on');
                imagesc(app.azel_init_axes,app.experiment.xVec,app.experiment.yVec,squeeze(displayVol(ra,:,:)));
                colormap(app.azel_init_axes,gray);
                green = zeros(ySize,zSize,3);
                green(:,:,2) = 1; 
                h = imshow(green, 'XData',app.experiment.xVec,'YData',app.experiment.yVec,'Parent',app.azel_init_axes);
                axis(app.azel_init_axes,'on')
                set(h,'AlphaData',overLayImAzel);
                xlim(app.azel_init_axes,[app.experiment.xVec(1),app.experiment.xVec(end)]);
                ylim(app.azel_init_axes,[app.experiment.yVec(1),app.experiment.yVec(end)]);
            hold(app.azel_init_axes,'off')
            
            %% Ra - Az
            cla(app.raaz_init_axes);
            hold(app.raaz_init_axes,'on');
                imagesc(app.raaz_init_axes,app.experiment.yVec,app.experiment.zVec,squeeze(displayVol(:,:,elev)));
                colormap(app.raaz_init_axes,gray);
                green = zeros(xSize,zSize,3);
                green(:,:,2) = 1; 
                k = imshow(green, 'XData',app.experiment.yVec,'YData',app.experiment.zVec,'Parent',app.raaz_init_axes);
                axis(app.raaz_init_axes,'on')
                set(k,'AlphaData',overLayImRaaz');
                xlim(app.raaz_init_axes,[app.experiment.yVec(1),app.experiment.yVec(end)]);
                ylim(app.raaz_init_axes,[app.experiment.zVec(1),app.experiment.zVec(end)]);
                set(app.raaz_init_axes,'YDir','reverse') 
            hold(app.raaz_init_axes,'off')
            
            %% Ra - El 
            cla(app.rael_init_axes);
            hold(app.rael_init_axes,'on');
                imagesc(app.rael_init_axes,app.experiment.xVec,app.experiment.zVec,squeeze(displayVol(:,azim,:)))
                colormap(app.rael_init_axes,gray);
                green = zeros(xSize,zSize,3);
                green(:,:,2) = 1; 
                l = imshow(green, 'XData',app.experiment.xVec,'YData',app.experiment.zVec,'Parent',app.rael_init_axes);
                axis(app.rael_init_axes,'on')
                set(l,'AlphaData',overLayImRael');
                xlim(app.rael_init_axes,[app.experiment.xVec(1),app.experiment.xVec(end)]);
                ylim(app.rael_init_axes,[app.experiment.zVec(1),app.experiment.zVec(end)]);
            hold(app.rael_init_axes,'off')
            
            %%3d plot
            %patch(app.surf_init_axes ,isosurface(testROI))
        end
        function updateDirectory(app)
            app.DirectoryLabel.Text = app.experiment.dataFolder; 
            
        end
        function updateImagingAxis(app)
            initImagingAxes(app)
            [xMax,yMax,zMax,frames] = size(app.experiment.initDataSet.rawData_cart);
            azim = floor(app.AzimuthSlider_imaging.Value);
            elev = floor(app.ElevationSlider_imaging.Value); 
            ra = floor(app.RangeSlider_imaging.Value); 
            displayVol = log10(abs(squeeze(app.experiment.ultrasoundDataSeries(end).rawData_cart(:,:,:,1)))); 
            imagesc(app.azel_imaging_axes,squeeze(displayVol(ra,:,:)));
            set(app.azel_imaging_axes, 'XTick', 1:30:yMax, 'XTickLabel', app.experiment.initDataSet.y_range(1:10:yMax))
            set(app.azel_imaging_axes, 'YTick', 1:30:zMax, 'YTickLabel', app.experiment.initDataSet.z_range(1:10:zMax))
            colormap(app.azel_imaging_axes,gray);
            xlim(app.azel_imaging_axes,[1 yMax]);
            ylim(app.azel_imaging_axes,[1 zMax]);
            %hold(app.azel_init_axes,'off')
            %axes(app.raaz_init_axes);
            cla(app.raaz_imaging_axes);
            imagesc(app.raaz_imaging_axes,squeeze(displayVol(:,:,elev)))
            colormap(app.raaz_imaging_axes,gray);
            xlim(app.raaz_imaging_axes,[1 xMax]);
            ylim(app.raaz_imaging_axes,[1 yMax]);

            
            %axes(app.rael_init_axes);
            imagesc(app.rael_imaging_axes,squeeze(displayVol(:,azim,:)))
            colormap(app.rael_imaging_axes,gray);
            xlim(app.rael_init_axes,[1 xMax]);
            ylim(app.rael_init_axes,[1 zMax]);
            %plot(app.decorr_imaging_axes,app.experiment.)
        end
        function update3DinitAxes(app)
            [x_e, y_e, z_e] = ellipsoid(app.experiment.ROIy0,app.experiment.ROIz0,app.experiment.ROIx0,app.experiment.ROIr1,app.experiment.ROIr2,app.experiment.ROIr0,30);
            surf(app.surf_init_axes,x_e,y_e,z_e);
        end
        function runExperiment(app)
            
        end
        function nextDataSet(app)
            output = app.experiment.addNextDataSet();
            if output == -1
                % unable to add folder
                
            end
        end
 
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.experiment = ExperimentClass(); 
        end

        % Button pushed function: SetDirectoryButton
        function SetDirectoryButtonPushed(app, event)
            app.experiment.initDataFolderGUI();
            updateDirectory(app);
        end

        % Button pushed function: UpdateAllButton
        function UpdateAllButtonPushed(app, event)
            
        end

        % Value changed function: FrameRateEditField
        function FrameRateEditFieldValueChanged(app, event)
            updateImagingParams(app)
        end

        % Value changed function: AzimuthAngleEditField
        function AzimuthAngleEditFieldValueChanged(app, event)
            updateImagingParams(app)
        end

        % Value changed function: ElevationAngleEditField
        function ElevationAngleEditFieldValueChanged(app, event)
            updateImagingParams(app)
        end

        % Value changed function: CartScalingFactorEditField
        function CartScalingFactorEditFieldValueChanged(app, event)
            updateImagingParams(app)
        end

        % Button pushed function: RequestButton
        function RequestButtonPushed(app, event)
            app.RequestButton.Text = "Processing";
            updateImagingParams(app)
            app.experiment.getInitDataSet(); 
            initInitAxes(app);
            app.RequestButton.Text = "Request";
        end

        % Value changed function: AzimuthSlider
        function AzimuthSliderValueChanged(app, event)
            updateInitAxes(app)
        end

        % Value changed function: RangeSlider
        function RangeSliderValueChanged(app, event)
           updateInitAxes(app)
        end

        % Value changed function: ElevationSlider
        function ElevationSliderValueChanged(app, event)
            updateInitAxes(app)
        end

        % Value changed function: ROIModeDropDown
        function ROIModeDropDownValueChanged(app, event)
            updateROIParams(app);
            update3DinitAxes(app);
        end

        % Value changed function: Rx_outEditField
        function Rx_outEditFieldValueChanged(app, event)
            updateROIParams(app);
        end

        % Value changed function: x0EditField
        function x0EditFieldValueChanged(app, event)
            updateROIParams(app);
        end

        % Value changed function: y0EditField
        function y0EditFieldValueChanged(app, event)
            updateROIParams(app);
        end

        % Value changed function: z0EditField
        function z0EditFieldValueChanged(app, event)
            updateROIParams(app);
        end

        % Value changed function: Ry_outEditField
        function Ry_outEditFieldValueChanged(app, event)
            updateROIParams(app);
        end

        % Value changed function: Rz_outEditField
        function Rz_outEditFieldValueChanged(app, event)
            updateROIParams(app);
        end

        % Value changed function: Rx_inEditField
        function Rx_inEditFieldValueChanged(app, event)
            updateROIParams(app);
        end

        % Value changed function: Ry_inEditField
        function Ry_inEditFieldValueChanged(app, event)
             updateROIParams(app);
        end

        % Value changed function: Rz_inEditField
        function Rz_inEditFieldValueChanged(app, event)
            updateROIParams(app);
        end

        % Value changed function: AlphaEditField
        function AlphaEditFieldValueChanged(app, event)
            updateROIParams(app);
        end

        % Value changed function: BetaEditField
        function BetaEditFieldValueChanged(app, event)
            updateROIParams(app);
        end

        % Value changed function: GammaEditField
        function GammaEditFieldValueChanged(app, event)
            updateROIParams(app);
        end

        % Button pushed function: RecordVolumeButton
        function RecordVolumeButtonPushed(app, event)
            nextDataSet(app);
            updateImagingAxis(app);
        end

        % Value changed function: DecorrThreshEditField
        function DecorrThreshEditFieldValueChanged(app, event)
            myThresh = app.DecorrThreshEditField.Value;
            mySigma = app.sigmaEditField.Value; 
            app.experiment.setControlParams(myThresh, mySigma);
        end

        % Value changed function: sigmaEditField
        function sigmaEditFieldValueChanged(app, event)
            myThresh = app.DecorrThreshEditField.Value;
            mySigma = app.sigmaEditField.Value; 
            app.experiment.setControlParams(myThresh, mySigma);
        end

        % Value changed function: AzimuthSlider_imaging
        function AzimuthSlider_imagingValueChanged(app, event)
            updateImagingAxis(app)
            
        end

        % Value changed function: ElevationSlider_imaging
        function ElevationSlider_imagingValueChanged(app, event)

            updateImagingAxis(app)
        end

        % Value changing function: ElevationSlider_imaging
        function ElevationSlider_imagingValueChanging(app, event)
            updateImagingAxis(app)
            
        end

        % Value changed function: RangeSlider_imaging
        function RangeSlider_imagingValueChanged(app, event)
            updateImagingAxis(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ResetAppMenu = uimenu(app.FileMenu);
            app.ResetAppMenu.Text = 'Reset App';
            app.ImportImagingSettingsMenu = uimenu(app.FileMenu);
            app.ImportImagingSettingsMenu.Text = 'Import Imaging Settings';
            app.ImportControlSettingsMenu = uimenu(app.FileMenu);
            app.ImportControlSettingsMenu.Text = 'Import Control Settings';
            app.ExportImagingSettingsMenu = uimenu(app.FileMenu);
            app.ExportImagingSettingsMenu.Text = 'Export Imaging Settings';
            app.ExportControlSettingsMenu = uimenu(app.FileMenu);
            app.ExportControlSettingsMenu.Text = 'Export Control Settings';
            app.TestSerialConnectionMenu = uimenu(app.FileMenu);
            app.TestSerialConnectionMenu.Text = 'Test Serial Connection';
            app.ControlMenu = uimenu(app.UIFigure);
            app.ControlMenu.Text = 'Control';
            app.ResetControlParametersMenu = uimenu(app.ControlMenu);
            app.ResetControlParametersMenu.Text = 'Reset Control Parameters';
            app.Menu = uimenu(app.ControlMenu);
            app.ReviewMenu = uimenu(app.UIFigure);
            app.ReviewMenu.Text = 'Review';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.SerialConnectionsPanel = uipanel(app.SetupTab);
            app.SerialConnectionsPanel.Title = 'Serial Connections';
            app.RFControllerLabel = uilabel(app.SerialConnectionsPanel);
            app.RFControllerLabel.Text = 'RF Controller ';
            app.RFControllerDropDown = uidropdown(app.SerialConnectionsPanel);
            app.RFOutputDropDownLabel = uilabel(app.SerialConnectionsPanel);
            app.RFOutputDropDownLabel.Text = 'RF Output';
            app.RFOutputDropDown = uidropdown(app.SerialConnectionsPanel);
            app.VerifyButton = uibutton(app.SerialConnectionsPanel, 'push');
            app.VerifyButton.Text = 'Verify';
            app.VerifyButton_2 = uibutton(app.SerialConnectionsPanel, 'push');
            app.VerifyButton_2.Text = 'Verify';
            app.EnableSwitchLabel = uilabel(app.SerialConnectionsPanel);
            app.EnableSwitchLabel.Text = 'Enable';
            app.EnableSwitch = uiswitch(app.SerialConnectionsPanel, 'slider');
            app.EnableSwitch_2Label = uilabel(app.SerialConnectionsPanel);
            app.EnableSwitch_2Label.Text = 'Enable';
            app.EnableSwitch_2 = uiswitch(app.SerialConnectionsPanel, 'slider');
            app.GeneralControlsPanel = uipanel(app.SetupTab);
            app.GeneralControlsPanel.Title = 'General Controls';
            app.ModeButtonGroup = uibuttongroup(app.GeneralControlsPanel);
            app.ModeButtonGroup.Title = 'Mode';
            app.ControlModeButton = uiradiobutton(app.ModeButtonGroup);
            app.ControlModeButton.Text = 'Control Mode';
            app.ControlModeButton.Value = true;
            app.PrerecordedDataModeButton = uiradiobutton(app.ModeButtonGroup);
            app.PrerecordedDataModeButton.Text = 'Prerecorded Data Mode';
            app.ImagingModeButton = uiradiobutton(app.ModeButtonGroup);
            app.ImagingModeButton.Text = 'Imaging Mode';
            app.azel_init_axes = uiaxes(app.SetupTab);
            title(app.azel_init_axes, 'Azimuth-Elevation Plane')
            xlabel(app.azel_init_axes, '')
            ylabel(app.azel_init_axes, '')
            app.ImagingSettingsPanel = uipanel(app.SetupTab);
            app.ImagingSettingsPanel.Title = 'Imaging Settings';
            app.AzimuthAngleEditFieldLabel = uilabel(app.ImagingSettingsPanel);
            app.AzimuthAngleEditFieldLabel.Text = 'Azimuth Angle';
            app.AzimuthAngleEditField = uieditfield(app.ImagingSettingsPanel, 'numeric');
            app.AzimuthAngleEditField.ValueChangedFcn = createCallbackFcn(app, @AzimuthAngleEditFieldValueChanged, true);
            app.ElevationAngleEditFieldLabel = uilabel(app.ImagingSettingsPanel);
            app.ElevationAngleEditFieldLabel.Text = 'Elevation Angle';
            app.ElevationAngleEditField = uieditfield(app.ImagingSettingsPanel, 'numeric');
            app.ElevationAngleEditField.ValueChangedFcn = createCallbackFcn(app, @ElevationAngleEditFieldValueChanged, true);
            app.FrameRateEditFieldLabel = uilabel(app.ImagingSettingsPanel);
            app.FrameRateEditFieldLabel.Text = 'Frame Rate';
            app.FrameRateEditField = uieditfield(app.ImagingSettingsPanel, 'numeric');
            app.FrameRateEditField.ValueChangedFcn = createCallbackFcn(app, @FrameRateEditFieldValueChanged, true);
            app.CartScalingFactorEditFieldLabel = uilabel(app.ImagingSettingsPanel);
            app.CartScalingFactorEditFieldLabel.Text = 'Cart Scaling Factor';
            app.CartScalingFactorEditField = uieditfield(app.ImagingSettingsPanel, 'numeric');
            app.CartScalingFactorEditField.ValueChangedFcn = createCallbackFcn(app, @CartScalingFactorEditFieldValueChanged, true);
            app.TargetDirectoryPanel = uipanel(app.SetupTab);
            app.TargetDirectoryPanel.Title = 'Target Directory';
            app.SetDirectoryButton = uibutton(app.TargetDirectoryPanel, 'push');
            app.SetDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SetDirectoryButtonPushed, true);
            app.SetDirectoryButton.Text = 'Set Directory';
            app.DirectoryLabel = uilabel(app.TargetDirectoryPanel);
            app.DirectoryLabel.Text = 'Directory';
            app.ROISettingsPanel = uipanel(app.SetupTab);
            app.ROISettingsPanel.Title = 'ROI Settings';
            app.ROIModeDropDownLabel = uilabel(app.ROISettingsPanel);
            app.ROIModeDropDownLabel.Text = 'ROI Mode';
            app.ROIModeDropDown = uidropdown(app.ROISettingsPanel);
            app.ROIModeDropDown.Items = {'Single Ellipsoid', 'Concentric Ellipsoids', 'Ellipsoid with regions', 'Concentric Ellipsoid + regions'};
            app.ROIModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ROIModeDropDownValueChanged, true);
            app.ROIModeDropDown.Value = 'Single Ellipsoid';
            app.ROICenterLabel = uilabel(app.ROISettingsPanel);
            app.ROICenterLabel.Text = 'ROI Center';
            app.OuterRadiiLabel = uilabel(app.ROISettingsPanel);
            app.OuterRadiiLabel.Text = 'Outer Radii';
            app.InnerRadiiLabel = uilabel(app.ROISettingsPanel);
            app.InnerRadiiLabel.Text = 'Inner Radii';
            app.x0EditFieldLabel = uilabel(app.ROISettingsPanel);
            app.x0EditFieldLabel.Text = 'x0';
            app.x0EditField = uieditfield(app.ROISettingsPanel, 'numeric');
            app.x0EditField.ValueChangedFcn = createCallbackFcn(app, @x0EditFieldValueChanged, true);
            app.y0EditFieldLabel = uilabel(app.ROISettingsPanel);
            app.y0EditFieldLabel.Text = 'y0';
            app.y0EditField = uieditfield(app.ROISettingsPanel, 'numeric');
            app.y0EditField.ValueChangedFcn = createCallbackFcn(app, @y0EditFieldValueChanged, true);
            app.z0EditFieldLabel = uilabel(app.ROISettingsPanel);
            app.z0EditFieldLabel.Text = 'z0';
            app.z0EditField = uieditfield(app.ROISettingsPanel, 'numeric');
            app.z0EditField.ValueChangedFcn = createCallbackFcn(app, @z0EditFieldValueChanged, true);
            app.Rx_outEditFieldLabel = uilabel(app.ROISettingsPanel);
            app.Rx_outEditFieldLabel.Text = 'Rx_out';
            app.Rx_outEditField = uieditfield(app.ROISettingsPanel, 'numeric');
            app.Rx_outEditField.ValueChangedFcn = createCallbackFcn(app, @Rx_outEditFieldValueChanged, true);
            app.Ry_outEditFieldLabel = uilabel(app.ROISettingsPanel);
            app.Ry_outEditFieldLabel.Text = 'Ry_out';
            app.Ry_outEditField = uieditfield(app.ROISettingsPanel, 'numeric');
            app.Ry_outEditField.ValueChangedFcn = createCallbackFcn(app, @Ry_outEditFieldValueChanged, true);
            app.Rz_outEditFieldLabel = uilabel(app.ROISettingsPanel);
            app.Rz_outEditFieldLabel.Text = 'Rz_out';
            app.Rz_outEditField = uieditfield(app.ROISettingsPanel, 'numeric');
            app.Rz_outEditField.ValueChangedFcn = createCallbackFcn(app, @Rz_outEditFieldValueChanged, true);
            app.Rx_inEditFieldLabel = uilabel(app.ROISettingsPanel);
            app.Rx_inEditFieldLabel.Text = 'Rx_in';
            app.Rx_inEditField = uieditfield(app.ROISettingsPanel, 'numeric');
            app.Rx_inEditField.ValueChangedFcn = createCallbackFcn(app, @Rx_inEditFieldValueChanged, true);
            app.Ry_inEditFieldLabel = uilabel(app.ROISettingsPanel);
            app.Ry_inEditFieldLabel.Text = 'Ry_in';
            app.Ry_inEditField = uieditfield(app.ROISettingsPanel, 'numeric');
            app.Ry_inEditField.ValueChangedFcn = createCallbackFcn(app, @Ry_inEditFieldValueChanged, true);
            app.Rz_inEditFieldLabel = uilabel(app.ROISettingsPanel);
            app.Rz_inEditFieldLabel.Text = 'Rz_in';
            app.Rz_inEditField = uieditfield(app.ROISettingsPanel, 'numeric');
            app.Rz_inEditField.ValueChangedFcn = createCallbackFcn(app, @Rz_inEditFieldValueChanged, true);
            app.AngleLabel = uilabel(app.ROISettingsPanel);
            app.AngleLabel.Text = 'Angle ';
            app.AlphaEditFieldLabel = uilabel(app.ROISettingsPanel);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.ROISettingsPanel, 'numeric');
            app.AlphaEditField.ValueChangedFcn = createCallbackFcn(app, @AlphaEditFieldValueChanged, true);
            app.BetaEditFieldLabel = uilabel(app.ROISettingsPanel);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.ROISettingsPanel, 'numeric');
            app.BetaEditField.ValueChangedFcn = createCallbackFcn(app, @BetaEditFieldValueChanged, true);
            app.GammaEditFieldLabel = uilabel(app.ROISettingsPanel);
            app.GammaEditFieldLabel.Text = 'Gamma';
            app.GammaEditField = uieditfield(app.ROISettingsPanel, 'numeric');
            app.GammaEditField.ValueChangedFcn = createCallbackFcn(app, @GammaEditFieldValueChanged, true);
            app.RangeSliderLabel = uilabel(app.SetupTab);
            app.RangeSliderLabel.Text = 'Range';
            app.RangeSlider = uislider(app.SetupTab);
            app.RangeSlider.ValueChangedFcn = createCallbackFcn(app, @RangeSliderValueChanged, true);
            app.rael_init_axes = uiaxes(app.SetupTab);
            title(app.rael_init_axes, 'Range-Elevation Plane')
            xlabel(app.rael_init_axes, '')
            ylabel(app.rael_init_axes, '')
            app.AzimuthSliderLabel = uilabel(app.SetupTab);
            app.AzimuthSliderLabel.Text = 'Azimuth';
            app.AzimuthSlider = uislider(app.SetupTab);
            app.AzimuthSlider.ValueChangedFcn = createCallbackFcn(app, @AzimuthSliderValueChanged, true);
            app.raaz_init_axes = uiaxes(app.SetupTab);
            title(app.raaz_init_axes, 'Range-Azimuth Plane')
            xlabel(app.raaz_init_axes, '')
            ylabel(app.raaz_init_axes, '')
            app.raaz_init_axes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.ElevationSliderLabel = uilabel(app.SetupTab);
            app.ElevationSliderLabel.Text = 'Elevation';
            app.ElevationSlider = uislider(app.SetupTab);
            app.ElevationSlider.ValueChangedFcn = createCallbackFcn(app, @ElevationSliderValueChanged, true);
            app.RequestButton = uibutton(app.SetupTab, 'push');
            app.RequestButton.ButtonPushedFcn = createCallbackFcn(app, @RequestButtonPushed, true);
            app.RequestButton.Text = 'Request';
            app.RequestInitVolumeLabel = uilabel(app.SetupTab);
            app.RequestInitVolumeLabel.Text = 'Request Init Volume';
            app.EtcPanel = uipanel(app.SetupTab);
            app.EtcPanel.Title = 'Etc';
            app.UpdateAllButton = uibutton(app.EtcPanel, 'push');
            app.UpdateAllButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateAllButtonPushed, true);
            app.UpdateAllButton.Text = 'Update All';
            app.ResetButton = uibutton(app.EtcPanel, 'push');
            app.ResetButton.Text = 'Reset';
            app.ExportSettingsButton = uibutton(app.EtcPanel, 'push');
            app.ExportSettingsButton.Text = 'Export Settings';
            app.ImportSettingsButton = uibutton(app.EtcPanel, 'push');
            app.ImportSettingsButton.Text = 'Import Settings';
            app.DynamicRangeSliderLabel = uilabel(app.SetupTab);
            app.DynamicRangeSliderLabel.Text = 'Dynamic Range';
            app.DynamicRangeSlider = uislider(app.SetupTab);
            app.surf_init_axes = uiaxes(app.SetupTab);
            title(app.surf_init_axes, '3D ROI')
            xlabel(app.surf_init_axes, '')
            ylabel(app.surf_init_axes, '')
            app.ImagingTab = uitab(app.TabGroup);
            app.ImagingTab.Title = 'Imaging';
            app.Panel = uipanel(app.ImagingTab);
            app.Panel.Title = 'Panel';
            app.RecordVolumeButton = uibutton(app.Panel, 'push');
            app.RecordVolumeButton.ButtonPushedFcn = createCallbackFcn(app, @RecordVolumeButtonPushed, true);
            app.RecordVolumeButton.Text = 'Record Volume';
            app.ButtonGroup = uibuttongroup(app.Panel);
            app.ButtonGroup.Title = 'Button Group';
            app.ManualImagingMdoeButton = uiradiobutton(app.ButtonGroup);
            app.ManualImagingMdoeButton.Text = 'Manual Imaging Mdoe';
            app.ManualImagingMdoeButton.Value = true;
            app.AutomaticImagingModeButton = uiradiobutton(app.ButtonGroup);
            app.AutomaticImagingModeButton.Text = 'Automatic Imaging Mode';
            app.ManualControlModeButton = uiradiobutton(app.ButtonGroup);
            app.ManualControlModeButton.Text = 'Manual Control Mode';
            app.AutomaticControlModeButton = uiradiobutton(app.ButtonGroup);
            app.AutomaticControlModeButton.Text = 'Automatic Control Mode';
            app.ReadytoRecordLampLabel = uilabel(app.Panel);
            app.ReadytoRecordLampLabel.Text = 'Ready to Record';
            app.ReadytoRecordLamp = uilamp(app.Panel);
            app.DecorrThreshEditFieldLabel = uilabel(app.Panel);
            app.DecorrThreshEditFieldLabel.Text = 'Decorr Thresh';
            app.DecorrThreshEditField = uieditfield(app.Panel, 'numeric');
            app.DecorrThreshEditField.ValueChangedFcn = createCallbackFcn(app, @DecorrThreshEditFieldValueChanged, true);
            app.sigmaEditFieldLabel = uilabel(app.Panel);
            app.sigmaEditFieldLabel.Text = 'sigma';
            app.sigmaEditField = uieditfield(app.Panel, 'numeric');
            app.sigmaEditField.ValueChangedFcn = createCallbackFcn(app, @sigmaEditFieldValueChanged, true);
            app.decorr_imaging_axes = uiaxes(app.ImagingTab);
            title(app.decorr_imaging_axes, 'Decorrelation Over TIme')
            xlabel(app.decorr_imaging_axes, 'X')
            ylabel(app.decorr_imaging_axes, 'Y')
            app.azel_imaging_axes = uiaxes(app.ImagingTab);
            title(app.azel_imaging_axes, 'Azimuth-Elevation Plane')
            xlabel(app.azel_imaging_axes, '')
            ylabel(app.azel_imaging_axes, '')
            app.rael_imaging_axes = uiaxes(app.ImagingTab);
            title(app.rael_imaging_axes, 'Range-Elevation Plane')
            xlabel(app.rael_imaging_axes, '')
            ylabel(app.rael_imaging_axes, '')
            app.raaz_imaging_axes = uiaxes(app.ImagingTab);
            title(app.raaz_imaging_axes, 'Range-Azimuth Plane')
            xlabel(app.raaz_imaging_axes, '')
            ylabel(app.raaz_imaging_axes, '')
            app.RangeSlider_2Label = uilabel(app.ImagingTab);
            app.RangeSlider_2Label.Text = 'Range';
            app.RangeSlider_imaging = uislider(app.ImagingTab);
            app.RangeSlider_imaging.ValueChangedFcn = createCallbackFcn(app, @RangeSlider_imagingValueChanged, true);
            app.AzimuthSlider_2Label = uilabel(app.ImagingTab);
            app.AzimuthSlider_2Label.Text = 'Azimuth';
            app.AzimuthSlider_imaging = uislider(app.ImagingTab);
            app.AzimuthSlider_imaging.ValueChangedFcn = createCallbackFcn(app, @AzimuthSlider_imagingValueChanged, true);
            app.ElevationSlider_2Label = uilabel(app.ImagingTab);
            app.ElevationSlider_2Label.Text = 'Elevation';
            app.ElevationSlider_imaging = uislider(app.ImagingTab);
            app.ElevationSlider_imaging.ValueChangedFcn = createCallbackFcn(app, @ElevationSlider_imagingValueChanged, true);
            app.ElevationSlider_imaging.ValueChangingFcn = createCallbackFcn(app, @ElevationSlider_imagingValueChanging, true);
            app.DynamicRangeSlider_2Label = uilabel(app.ImagingTab);
            app.DynamicRangeSlider_2Label.Text = 'Dynamic Range';
            app.DynamicRangeSlider_imaging = uislider(app.ImagingTab);
            app.ReviewTab = uitab(app.TabGroup);
            app.ReviewTab.Title = 'Review';
            app.UIAxes_5 = uiaxes(app.ReviewTab);
            title(app.UIAxes_5, 'Azimuth-Elevation Plane')
            xlabel(app.UIAxes_5, '')
            ylabel(app.UIAxes_5, '')
            app.UIAxes_6 = uiaxes(app.ReviewTab);
            title(app.UIAxes_6, 'Title')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            app.UIAxes_7 = uiaxes(app.ReviewTab);
            title(app.UIAxes_7, 'Title')
            xlabel(app.UIAxes_7, 'X')
            ylabel(app.UIAxes_7, 'Y')
            app.UIAxes_8 = uiaxes(app.ReviewTab);
            title(app.UIAxes_8, 'Title')
            xlabel(app.UIAxes_8, 'X')
            ylabel(app.UIAxes_8, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DecorrApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end